classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        HistogramTab               matlab.ui.container.Tab
        HistogramImage             matlab.ui.control.Image
        OpenedFileLabel            matlab.ui.control.Label
        OpenButton                 matlab.ui.control.Button
        HistogramUIAxes            matlab.ui.control.UIAxes
        ImageBrighteningTab        matlab.ui.container.Tab
        bSlider                    matlab.ui.control.Slider
        bSliderLabel               matlab.ui.control.Label
        aSlider                    matlab.ui.control.Slider
        aSliderLabel               matlab.ui.control.Label
        AfterLabel                 matlab.ui.control.Label
        BrighAfterImage            matlab.ui.control.Image
        BrightBeforeImage          matlab.ui.control.Image
        BeforeLabel                matlab.ui.control.Label
        BrightOpenedLabel          matlab.ui.control.Label
        BrightOpenButton           matlab.ui.control.Button
        BrightAfterAxes            matlab.ui.control.UIAxes
        BrightBeforeAxes           matlab.ui.control.UIAxes
        LogTransformationTab       matlab.ui.container.Tab
        cSlider                    matlab.ui.control.Slider
        cSliderLabel               matlab.ui.control.Label
        AfterLabel_2               matlab.ui.control.Label
        LogAfterImage              matlab.ui.control.Image
        LogBeforeImage             matlab.ui.control.Image
        BeforeLabel_2              matlab.ui.control.Label
        LogOpenedLabel             matlab.ui.control.Label
        LogOpenButton              matlab.ui.control.Button
        LogAfterAxes               matlab.ui.control.UIAxes
        LogBeforeAxes              matlab.ui.control.UIAxes
        PowerTransformationTab     matlab.ui.container.Tab
        ySlider                    matlab.ui.control.Slider
        ySliderLabel               matlab.ui.control.Label
        cSliderPangkat             matlab.ui.control.Slider
        cSlider_2Label             matlab.ui.control.Label
        AfterLabel_3               matlab.ui.control.Label
        PangkatAfterImage          matlab.ui.control.Image
        PangkatBeforeImage         matlab.ui.control.Image
        BeforeLabel_3              matlab.ui.control.Label
        PangkatOpenedLabel         matlab.ui.control.Label
        PangkatOpenButton          matlab.ui.control.Button
        PangkatAfterAxes           matlab.ui.control.UIAxes
        PangkatBeforeAxes          matlab.ui.control.UIAxes
        ContrastStretchingTab      matlab.ui.container.Tab
        AfterLabel_4               matlab.ui.control.Label
        ContrastAfterImage         matlab.ui.control.Image
        ContrastBeforeImage        matlab.ui.control.Image
        BeforeLabel_4              matlab.ui.control.Label
        ContrastOpenedLabel        matlab.ui.control.Label
        ContrastOpenButton         matlab.ui.control.Button
        ContrastAfterAxes          matlab.ui.control.UIAxes
        ContrastBeforeAxes         matlab.ui.control.UIAxes
        HistogramEquilizationTab   matlab.ui.container.Tab
        AfterLabel_5               matlab.ui.control.Label
        EqualizationAfterImage     matlab.ui.control.Image
        EqualizationBeforeImage    matlab.ui.control.Image
        BeforeLabel_5              matlab.ui.control.Label
        EqualizationOpenedLabel    matlab.ui.control.Label
        EqualizationOpenButton     matlab.ui.control.Button
        EqualizationAfterAxes      matlab.ui.control.UIAxes
        EqualizationBeforeAxes     matlab.ui.control.UIAxes
        HistogramSpecificationTab  matlab.ui.container.Tab
        ExecuteButton              matlab.ui.control.Button
        ReferenceOpenedLabel       matlab.ui.control.Label
        ReferenceOpenButton        matlab.ui.control.Button
        OutputLabel                matlab.ui.control.Label
        OutputImage                matlab.ui.control.Image
        ReferenceImageLabel        matlab.ui.control.Label
        ReferenceImage             matlab.ui.control.Image
        InputImage                 matlab.ui.control.Image
        InputImageLabel            matlab.ui.control.Label
        InputOpenedLabel           matlab.ui.control.Label
        InputOpenButton            matlab.ui.control.Button
        OutputAxes                 matlab.ui.control.UIAxes
        ReferenceAxes              matlab.ui.control.UIAxes
        InputAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [filename, path] = uigetfile('*.*');
            app.OpenedFileLabel.Text = filename;

            imload = imread(filename);
            if(length(size(imload)) ~= 3)
                imload = repmat(imload, 1, 1, 3);
            end
            app.HistogramImage.ImageSource = imload;

            hist = raw_histogram(filename);
            bar(app.HistogramUIAxes, hist(1,:), hist(2,:));
        end

        % Button pushed function: BrightOpenButton
        function BrightOpenButtonPushed(app, event)
            [filename, path] = uigetfile('*.*');
            app.BrightOpenedLabel.Text = filename;

            imload = imread(filename);
            if(length(size(imload)) ~= 3)
                imload = repmat(imload, 1, 1, 3);
            end
            app.BrightBeforeImage.ImageSource = imload;
            before_hist = raw_histogram(filename);
            bar(app.BrightBeforeAxes, before_hist(1,:), before_hist(2,:));

            a = app.aSlider.Value;
            b = app.bSlider.Value;
            image_brightening(filename, a, b);
            after_hist = raw_histogram("output.jpg");
            bar(app.BrightAfterAxes, after_hist(1,:), after_hist(2,:));
            imload = imread("output.jpg");
            if(length(size(imload)) ~= 3)
                imload = repmat(imload, 1, 1, 3);
            end
            app.BrighAfterImage.ImageSource = imload;
            
        end

        % Value changed function: aSlider
        function aSliderValueChanged(app, event)
            a = app.aSlider.Value;
            filename = app.BrightOpenedLabel.Text;
            b = app.bSlider.Value;
            
            if(filename ~= "")
                image_brightening(filename, a, b);
                after_hist = raw_histogram("output.jpg");
                bar(app.BrightAfterAxes, after_hist(1,:), after_hist(2,:));
                imload = imread("output.jpg");
                if(length(size(imload)) ~= 3)
                    imload = repmat(imload, 1, 1, 3);
                end
                app.BrighAfterImage.ImageSource = imload;
            end
        end

        % Value changed function: bSlider
        function bSliderValueChanged(app, event)
            a = app.aSlider.Value;
            filename = app.BrightOpenedLabel.Text;
            b = app.bSlider.Value;

            if(filename ~= "")
                image_brightening(filename, a, b);
                after_hist = raw_histogram("output.jpg");
                bar(app.BrightAfterAxes, after_hist(1,:), after_hist(2,:));
                imload = imread("output.jpg");
                if(length(size(imload)) ~= 3)
                    imload = repmat(imload, 1, 1, 3);
                end
                app.BrighAfterImage.ImageSource = imload;
            end
        end

        % Button pushed function: LogOpenButton
        function LogOpenButtonPushed(app, event)
            [filename, path] = uigetfile('*.*');
            app.LogOpenedLabel.Text = filename;

            imload = imread(filename);
            if(length(size(imload)) ~= 3)
                imload = repmat(imload, 1, 1, 3);
            end
            app.LogBeforeImage.ImageSource = imload;

            hist = raw_histogram(filename);
            bar(app.LogBeforeAxes, hist(1,:), hist(2,:));

            c = app.cSlider.Value;
            transformasi_log(filename, c);
            after_hist = raw_histogram("output.jpg");
            bar(app.LogAfterAxes, after_hist(1,:), after_hist(2,:));
            imload = imread("output.jpg");
            if(length(size(imload)) ~= 3)
                imload = repmat(imload, 1, 1, 3);
            end
            app.LogAfterImage.ImageSource = imload;
        end

        % Value changed function: cSlider
        function cSliderValueChanged(app, event)
            c = app.cSlider.Value;
            filename = app.LogOpenedLabel.Text;

            if(filename ~= "")
                transformasi_log(filename, c);
                after_hist = raw_histogram("output.jpg");
                bar(app.LogAfterAxes, after_hist(1,:), after_hist(2,:));
                imload = imread("output.jpg");
                if(length(size(imload)) ~= 3)
                    imload = repmat(imload, 1, 1, 3);
                end
                app.LogAfterImage.ImageSource = imload;
            end
        end

        % Callback function
        function PangkatBeforeAxesButtonDown(app, event)
            
        end

        % Value changed function: cSliderPangkat
        function cSliderPangkatValueChanged(app, event)
            c = app.cSliderPangkat.Value;
            filename = app.PangkatOpenedLabel.Text;
            y = app.ySlider.Value;

            if(filename ~= "")
                transformasi_pangkat(filename, c, y);
                after_hist = raw_histogram("output.jpg");
                bar(app.PangkatAfterAxes, after_hist(1,:), after_hist(2,:));
                imload = imread("output.jpg");
                if(length(size(imload)) ~= 3)
                    imload = repmat(imload, 1, 1, 3);
                end
                app.PangkatAfterImage.ImageSource = imload;
            end
        end

        % Value changed function: ySlider
        function ySliderValueChanged(app, event)
            c = app.cSliderPangkat.Value;
            filename = app.PangkatOpenedLabel.Text;
            y = app.ySlider.Value;

            if(filename ~= "")
                transformasi_pangkat(filename, c, y);
                after_hist = raw_histogram("output.jpg");
                bar(app.PangkatAfterAxes, after_hist(1,:), after_hist(2,:));
                imload = imread("output.jpg");
                if(length(size(imload)) ~= 3)
                    imload = repmat(imload, 1, 1, 3);
                end
                app.PangkatAfterImage.ImageSource = imload;
            end
        end

        % Button pushed function: PangkatOpenButton
        function PangkatOpenButtonPushed(app, event)
            [filename, path] = uigetfile('*.*');
            app.PangkatOpenedLabel.Text = filename;

            imload = imread(filename);
            if(length(size(imload)) ~= 3)
                imload = repmat(imload, 1, 1, 3);
            end
            app.PangkatBeforeImage.ImageSource = imload;

            hist = raw_histogram(filename);
            bar(app.PangkatBeforeAxes, hist(1,:), hist(2,:));

            c = app.cSliderPangkat.Value;
            y = app.ySlider.Value;
            transformasi_pangkat(filename, c, y);
            after_hist = raw_histogram("output.jpg");
            bar(app.PangkatAfterAxes, after_hist(1,:), after_hist(2,:));
            imload = imread("output.jpg");
            if(length(size(imload)) ~= 3)
                imload = repmat(imload, 1, 1, 3);
            end
            app.PangkatAfterImage.ImageSource = imload;
        end

        % Button pushed function: ContrastOpenButton
        function ContrastOpenButtonPushed(app, event)
            [filename, path] = uigetfile('*.*');
            app.ContrastOpenedLabel.Text = filename;

            imload = imread(filename);
            if(length(size(imload)) ~= 3)
                imload = repmat(imload, 1, 1, 3);
            end
            app.ContrastBeforeImage.ImageSource = imload;

            hist = raw_histogram(filename);
            bar(app.ContrastBeforeAxes, hist(1,:), hist(2,:));

            contrast_strecthing(filename);
            after_hist = raw_histogram("output.jpg");
            bar(app.ContrastAfterAxes, after_hist(1,:), after_hist(2,:));
            imload = imread("output.jpg");
            if(length(size(imload)) ~= 3)
                imload = repmat(imload, 1, 1, 3);
            end
            app.ContrastAfterImage.ImageSource = imload;
        end

        % Button pushed function: EqualizationOpenButton
        function EqualizationOpenButtonPushed(app, event)
            [filename, path] = uigetfile('*.*');
            app.EqualizationOpenedLabel.Text = filename;

            imload = imread(filename);
            if(length(size(imload)) ~= 3)
                imload = repmat(imload, 1, 1, 3);
            end
            app.EqualizationBeforeImage.ImageSource = imload;

            hist = raw_histogram(filename);
            bar(app.EqualizationBeforeAxes, hist(1,:), hist(2,:));

            show_histogram_equalization(filename);
            after_hist = raw_histogram("output.jpg");
            bar(app.EqualizationAfterAxes, after_hist(1,:), after_hist(2,:));
            imload = imread("output.jpg");
            if(length(size(imload)) ~= 3)
                imload = repmat(imload, 1, 1, 3);
            end
            app.EqualizationAfterImage.ImageSource = imload;
        end

        % Button pushed function: InputOpenButton
        function InputOpenButtonPushed(app, event)
            [filename, path] = uigetfile('*.*');
            app.InputOpenedLabel.Text = filename;

            imload = imread(filename);
            if(length(size(imload)) ~= 3)
                imload = repmat(imload, 1, 1, 3);
            end
            app.InputImage.ImageSource = imload;

            hist = raw_histogram(filename);
            bar(app.InputAxes, hist(1,:), hist(2,:));
        end

        % Button pushed function: ReferenceOpenButton
        function ReferenceOpenButtonPushed(app, event)
            [filename, path] = uigetfile('*.*');
            app.ReferenceOpenedLabel.Text = filename;

            imload = imread(filename);
            if(length(size(imload)) ~= 3)
                imload = repmat(imload, 1, 1, 3);
            end
            app.ReferenceImage.ImageSource = imload;

            hist = raw_histogram(filename);
            bar(app.ReferenceAxes, hist(1,:), hist(2,:));
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            file1 = app.InputOpenedLabel.Text;
            file2 = app.ReferenceOpenedLabel.Text;

            show_histogram_specification(file1, file2);
            after_hist = raw_histogram("output.jpg");
            bar(app.OutputAxes, after_hist(1,:), after_hist(2,:));
            imload = imread("output.jpg");
            if(length(size(imload)) ~= 3)
                imload = repmat(imload, 1, 1, 3);
            end
            app.OutputImage.ImageSource = imload;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            app.HistogramUIAxes = uiaxes(app.HistogramTab);
            title(app.HistogramUIAxes, 'Histogram')
            xlabel(app.HistogramUIAxes, 'Gray Level')
            ylabel(app.HistogramUIAxes, 'No. of Pixels')
            zlabel(app.HistogramUIAxes, 'Z')
            app.HistogramUIAxes.XTick = [0 50 100 150 200 250];
            app.HistogramUIAxes.XTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'};
            app.OpenButton = uibutton(app.HistogramTab, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.OpenedFileLabel = uilabel(app.HistogramTab);
            app.OpenedFileLabel.Text = '';
            app.HistogramImage = uiimage(app.HistogramTab);
            app.ImageBrighteningTab = uitab(app.TabGroup);
            app.ImageBrighteningTab.Title = 'Image Brightening';
            app.BrightBeforeAxes = uiaxes(app.ImageBrighteningTab);
            title(app.BrightBeforeAxes, 'Histogram')
            xlabel(app.BrightBeforeAxes, 'Gray Level')
            ylabel(app.BrightBeforeAxes, 'No. of Pixels')
            zlabel(app.BrightBeforeAxes, 'Z')
            app.BrightBeforeAxes.XTick = [0 50 100 150 200 250];
            app.BrightBeforeAxes.XTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'};
            app.BrightAfterAxes = uiaxes(app.ImageBrighteningTab);
            title(app.BrightAfterAxes, 'Histogram')
            xlabel(app.BrightAfterAxes, 'Gray Level')
            ylabel(app.BrightAfterAxes, 'No. of Pixels')
            zlabel(app.BrightAfterAxes, 'Z')
            app.BrightAfterAxes.XTick = [0 50 100 150 200 250];
            app.BrightAfterAxes.XTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'};
            app.BrightOpenButton = uibutton(app.ImageBrighteningTab, 'push');
            app.BrightOpenButton.ButtonPushedFcn = createCallbackFcn(app, @BrightOpenButtonPushed, true);
            app.BrightOpenButton.Text = 'Open';
            app.BrightOpenedLabel = uilabel(app.ImageBrighteningTab);
            app.BrightOpenedLabel.Text = '';
            app.BeforeLabel = uilabel(app.ImageBrighteningTab);
            app.BeforeLabel.Text = 'Before';
            app.BrightBeforeImage = uiimage(app.ImageBrighteningTab);
            app.BrighAfterImage = uiimage(app.ImageBrighteningTab);
            app.AfterLabel = uilabel(app.ImageBrighteningTab);
            app.AfterLabel.Text = 'After';
            app.aSliderLabel = uilabel(app.ImageBrighteningTab);
            app.aSliderLabel.Text = 'a';
            app.aSlider = uislider(app.ImageBrighteningTab);
            app.aSlider.Limits = [0 10];
            app.aSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.aSlider.ValueChangedFcn = createCallbackFcn(app, @aSliderValueChanged, true);
            app.bSliderLabel = uilabel(app.ImageBrighteningTab);
            app.bSliderLabel.Text = 'b';
            app.bSlider = uislider(app.ImageBrighteningTab);
            app.bSlider.MajorTicks = [0 20 40 60 80 100];
            app.bSlider.ValueChangedFcn = createCallbackFcn(app, @bSliderValueChanged, true);
            app.LogTransformationTab = uitab(app.TabGroup);
            app.LogTransformationTab.Title = 'Log Transformation';
            app.LogBeforeAxes = uiaxes(app.LogTransformationTab);
            title(app.LogBeforeAxes, 'Histogram')
            xlabel(app.LogBeforeAxes, 'Gray Level')
            ylabel(app.LogBeforeAxes, 'No. of Pixels')
            zlabel(app.LogBeforeAxes, 'Z')
            app.LogBeforeAxes.XTick = [0 50 100 150 200 250];
            app.LogBeforeAxes.XTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'};
            app.LogAfterAxes = uiaxes(app.LogTransformationTab);
            title(app.LogAfterAxes, 'Histogram')
            xlabel(app.LogAfterAxes, 'Gray Level')
            ylabel(app.LogAfterAxes, 'No. of Pixels')
            zlabel(app.LogAfterAxes, 'Z')
            app.LogAfterAxes.XTick = [0 50 100 150 200 250];
            app.LogAfterAxes.XTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'};
            app.LogOpenButton = uibutton(app.LogTransformationTab, 'push');
            app.LogOpenButton.ButtonPushedFcn = createCallbackFcn(app, @LogOpenButtonPushed, true);
            app.LogOpenButton.Text = 'Open';
            app.LogOpenedLabel = uilabel(app.LogTransformationTab);
            app.LogOpenedLabel.Text = '';
            app.BeforeLabel_2 = uilabel(app.LogTransformationTab);
            app.BeforeLabel_2.Text = 'Before';
            app.LogBeforeImage = uiimage(app.LogTransformationTab);
            app.LogAfterImage = uiimage(app.LogTransformationTab);
            app.AfterLabel_2 = uilabel(app.LogTransformationTab);
            app.AfterLabel_2.Text = 'After';
            app.cSliderLabel = uilabel(app.LogTransformationTab);
            app.cSliderLabel.Text = 'c';
            app.cSlider = uislider(app.LogTransformationTab);
            app.cSlider.Limits = [0 50];
            app.cSlider.MajorTicks = [0 10 20 30 40 50];
            app.cSlider.ValueChangedFcn = createCallbackFcn(app, @cSliderValueChanged, true);
            app.PowerTransformationTab = uitab(app.TabGroup);
            app.PowerTransformationTab.Title = 'Power Transformation';
            app.PangkatBeforeAxes = uiaxes(app.PowerTransformationTab);
            title(app.PangkatBeforeAxes, 'Histogram')
            xlabel(app.PangkatBeforeAxes, 'Gray Level')
            ylabel(app.PangkatBeforeAxes, 'No. of Pixels')
            zlabel(app.PangkatBeforeAxes, 'Z')
            app.PangkatBeforeAxes.XTick = [0 50 100 150 200 250];
            app.PangkatBeforeAxes.XTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'};
            app.PangkatAfterAxes = uiaxes(app.PowerTransformationTab);
            title(app.PangkatAfterAxes, 'Histogram')
            xlabel(app.PangkatAfterAxes, 'Gray Level')
            ylabel(app.PangkatAfterAxes, 'No. of Pixels')
            zlabel(app.PangkatAfterAxes, 'Z')
            app.PangkatAfterAxes.XTick = [0 50 100 150 200 250];
            app.PangkatAfterAxes.XTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'};
            app.PangkatOpenButton = uibutton(app.PowerTransformationTab, 'push');
            app.PangkatOpenButton.ButtonPushedFcn = createCallbackFcn(app, @PangkatOpenButtonPushed, true);
            app.PangkatOpenButton.Text = 'Open';
            app.PangkatOpenedLabel = uilabel(app.PowerTransformationTab);
            app.PangkatOpenedLabel.Text = '';
            app.BeforeLabel_3 = uilabel(app.PowerTransformationTab);
            app.BeforeLabel_3.Text = 'Before';
            app.PangkatBeforeImage = uiimage(app.PowerTransformationTab);
            app.PangkatAfterImage = uiimage(app.PowerTransformationTab);
            app.AfterLabel_3 = uilabel(app.PowerTransformationTab);
            app.AfterLabel_3.Text = 'After';
            app.cSlider_2Label = uilabel(app.PowerTransformationTab);
            app.cSlider_2Label.Text = 'c';
            app.cSliderPangkat = uislider(app.PowerTransformationTab);
            app.cSliderPangkat.Limits = [0 10];
            app.cSliderPangkat.MajorTicks = [0 2 4 6 8 10];
            app.cSliderPangkat.ValueChangedFcn = createCallbackFcn(app, @cSliderPangkatValueChanged, true);
            app.ySliderLabel = uilabel(app.PowerTransformationTab);
            app.ySliderLabel.Text = 'y';
            app.ySlider = uislider(app.PowerTransformationTab);
            app.ySlider.Limits = [0 10];
            app.ySlider.MajorTicks = [0 2 4 6 8 10];
            app.ySlider.ValueChangedFcn = createCallbackFcn(app, @ySliderValueChanged, true);
            app.ySlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.ContrastStretchingTab = uitab(app.TabGroup);
            app.ContrastStretchingTab.Title = 'Contrast Stretching';
            app.ContrastBeforeAxes = uiaxes(app.ContrastStretchingTab);
            title(app.ContrastBeforeAxes, 'Histogram')
            xlabel(app.ContrastBeforeAxes, 'Gray Level')
            ylabel(app.ContrastBeforeAxes, 'No. of Pixels')
            zlabel(app.ContrastBeforeAxes, 'Z')
            app.ContrastBeforeAxes.XTick = [0 50 100 150 200 250];
            app.ContrastBeforeAxes.XTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'};
            app.ContrastAfterAxes = uiaxes(app.ContrastStretchingTab);
            title(app.ContrastAfterAxes, 'Histogram')
            xlabel(app.ContrastAfterAxes, 'Gray Level')
            ylabel(app.ContrastAfterAxes, 'No. of Pixels')
            zlabel(app.ContrastAfterAxes, 'Z')
            app.ContrastAfterAxes.XTick = [0 50 100 150 200 250];
            app.ContrastAfterAxes.XTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'};
            app.ContrastOpenButton = uibutton(app.ContrastStretchingTab, 'push');
            app.ContrastOpenButton.ButtonPushedFcn = createCallbackFcn(app, @ContrastOpenButtonPushed, true);
            app.ContrastOpenButton.Text = 'Open';
            app.ContrastOpenedLabel = uilabel(app.ContrastStretchingTab);
            app.ContrastOpenedLabel.Text = '';
            app.BeforeLabel_4 = uilabel(app.ContrastStretchingTab);
            app.BeforeLabel_4.Text = 'Before';
            app.ContrastBeforeImage = uiimage(app.ContrastStretchingTab);
            app.ContrastAfterImage = uiimage(app.ContrastStretchingTab);
            app.AfterLabel_4 = uilabel(app.ContrastStretchingTab);
            app.AfterLabel_4.Text = 'After';
            app.HistogramEquilizationTab = uitab(app.TabGroup);
            app.HistogramEquilizationTab.Title = 'Histogram Equilization';
            app.EqualizationBeforeAxes = uiaxes(app.HistogramEquilizationTab);
            title(app.EqualizationBeforeAxes, 'Histogram')
            xlabel(app.EqualizationBeforeAxes, 'Gray Level')
            ylabel(app.EqualizationBeforeAxes, 'No. of Pixels')
            zlabel(app.EqualizationBeforeAxes, 'Z')
            app.EqualizationBeforeAxes.XTick = [0 50 100 150 200 250];
            app.EqualizationBeforeAxes.XTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'};
            app.EqualizationAfterAxes = uiaxes(app.HistogramEquilizationTab);
            title(app.EqualizationAfterAxes, 'Histogram')
            xlabel(app.EqualizationAfterAxes, 'Gray Level')
            ylabel(app.EqualizationAfterAxes, 'No. of Pixels')
            zlabel(app.EqualizationAfterAxes, 'Z')
            app.EqualizationAfterAxes.XTick = [0 50 100 150 200 250];
            app.EqualizationAfterAxes.XTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'};
            app.EqualizationOpenButton = uibutton(app.HistogramEquilizationTab, 'push');
            app.EqualizationOpenButton.ButtonPushedFcn = createCallbackFcn(app, @EqualizationOpenButtonPushed, true);
            app.EqualizationOpenButton.Text = 'Open';
            app.EqualizationOpenedLabel = uilabel(app.HistogramEquilizationTab);
            app.EqualizationOpenedLabel.Text = '';
            app.BeforeLabel_5 = uilabel(app.HistogramEquilizationTab);
            app.BeforeLabel_5.Text = 'Before';
            app.EqualizationBeforeImage = uiimage(app.HistogramEquilizationTab);
            app.EqualizationAfterImage = uiimage(app.HistogramEquilizationTab);
            app.AfterLabel_5 = uilabel(app.HistogramEquilizationTab);
            app.AfterLabel_5.Text = 'After';
            app.HistogramSpecificationTab = uitab(app.TabGroup);
            app.HistogramSpecificationTab.Title = 'Histogram Specification';
            app.InputAxes = uiaxes(app.HistogramSpecificationTab);
            title(app.InputAxes, 'Histogram')
            xlabel(app.InputAxes, 'Gray Level')
            ylabel(app.InputAxes, 'No. of Pixels')
            zlabel(app.InputAxes, 'Z')
            app.InputAxes.XTick = [0 50 100 150 200 250];
            app.InputAxes.XTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'};
            app.ReferenceAxes = uiaxes(app.HistogramSpecificationTab);
            title(app.ReferenceAxes, 'Histogram')
            xlabel(app.ReferenceAxes, 'Gray Level')
            ylabel(app.ReferenceAxes, 'No. of Pixels')
            zlabel(app.ReferenceAxes, 'Z')
            app.ReferenceAxes.XTick = [0 50 100 150 200 250];
            app.ReferenceAxes.XTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'};
            app.OutputAxes = uiaxes(app.HistogramSpecificationTab);
            title(app.OutputAxes, 'Histogram')
            xlabel(app.OutputAxes, 'Gray Level')
            ylabel(app.OutputAxes, 'No. of Pixels')
            zlabel(app.OutputAxes, 'Z')
            app.OutputAxes.XTick = [0 50 100 150 200 250];
            app.OutputAxes.XTickLabel = {'0'; '50'; '100'; '150'; '200'; '250'};
            app.InputOpenButton = uibutton(app.HistogramSpecificationTab, 'push');
            app.InputOpenButton.ButtonPushedFcn = createCallbackFcn(app, @InputOpenButtonPushed, true);
            app.InputOpenButton.Text = 'Open Input Image ';
            app.InputOpenedLabel = uilabel(app.HistogramSpecificationTab);
            app.InputOpenedLabel.Text = '';
            app.InputImageLabel = uilabel(app.HistogramSpecificationTab);
            app.InputImageLabel.Text = 'Input Image';
            app.InputImage = uiimage(app.HistogramSpecificationTab);
            app.ReferenceImage = uiimage(app.HistogramSpecificationTab);
            app.ReferenceImageLabel = uilabel(app.HistogramSpecificationTab);
            app.ReferenceImageLabel.Text = 'Reference Image';
            app.OutputImage = uiimage(app.HistogramSpecificationTab);
            app.OutputLabel = uilabel(app.HistogramSpecificationTab);
            app.OutputLabel.Text = 'Output';
            app.ReferenceOpenButton = uibutton(app.HistogramSpecificationTab, 'push');
            app.ReferenceOpenButton.ButtonPushedFcn = createCallbackFcn(app, @ReferenceOpenButtonPushed, true);
            app.ReferenceOpenButton.Text = 'Open Reference Image';
            app.ReferenceOpenedLabel = uilabel(app.HistogramSpecificationTab);
            app.ReferenceOpenedLabel.Text = '';
            app.ExecuteButton = uibutton(app.HistogramSpecificationTab, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end